classdef Analog_Modulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Modulacion_AnalogicaUIFigure  matlab.ui.Figure
        FaseDropDown                  matlab.ui.control.DropDown
        FaseDropDownLabel             matlab.ui.control.Label
        CuadriculaCheckBox            matlab.ui.control.CheckBox
        GenerarButton                 matlab.ui.control.Button
        npmEditField                  matlab.ui.control.NumericEditField
        npmEditFieldLabel             matlab.ui.control.Label
        nfmEditField                  matlab.ui.control.NumericEditField
        nfmEditFieldLabel             matlab.ui.control.Label
        mEditField                    matlab.ui.control.NumericEditField
        mEditFieldLabel               matlab.ui.control.Label
        IndicedemodulacinLabel        matlab.ui.control.Label
        CantidaddePeriodoscEditField  matlab.ui.control.NumericEditField
        CantidaddePeriodoscEditFieldLabel  matlab.ui.control.Label
        FrecuenciacEditField          matlab.ui.control.NumericEditField
        FrecuenciacEditFieldLabel     matlab.ui.control.Label
        AmplitudcEditField            matlab.ui.control.NumericEditField
        AmplitudcEditFieldLabel       matlab.ui.control.Label
        CarrierLabel                  matlab.ui.control.Label
        CantidaddePeriodosxEditField  matlab.ui.control.NumericEditField
        CantidaddePeriodosxEditFieldLabel  matlab.ui.control.Label
        DatosLabel                    matlab.ui.control.Label
        FrecuenciaxEditField          matlab.ui.control.NumericEditField
        FrecuenciaxEditFieldLabel     matlab.ui.control.Label
        AmplitudxEditField            matlab.ui.control.NumericEditField
        AmplitudxEditFieldLabel       matlab.ui.control.Label
        ModulacinAnalgicaLabel        matlab.ui.control.Label
        Grafica2                      matlab.ui.control.UIAxes
        Grafica1                      matlab.ui.control.UIAxes
        Grafica5                      matlab.ui.control.UIAxes
        Grafica4                      matlab.ui.control.UIAxes
        Grafica3                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            %Input---------------------------------------------------------
            %Ax
            Ax = app.AmplitudxEditField.Value;
            fx = app.FrecuenciaxEditField.Value;
            cpx = app.CantidaddePeriodosxEditField.Value;

            %Carrier
            Ac = app.AmplitudcEditField.Value;
            fc = app.FrecuenciacEditField.Value;
            cpc = app.CantidaddePeriodoscEditField.Value;
            Fase = app.FaseDropDown.Value;

            %Indice Modulación
            m = app.mEditField.Value;               % 0<= m <=1
            nfm = app.nfmEditField.Value;           % nfm >= 2
            npm = app.npmEditField.Value;           % npm >= 2

            %Process-------------------------------------------------------
            %xt
            Tx = 1/fx;
            tx = linspace(0, cpx*Tx, 1000);
            xt = Ax*sin(2*pi*fx*tx);
            
            %Carrier
            Tc = 1/fc;
            tc = linspace(0, cpc*Tc, 1000);
            if Fase == "Seno"
                phi_c = 0; %Seno
                xc = Ac*sin(2*pi*fc*tc+phi_c);
            elseif Fase =="Coseno"
                phi_c = pi/2; %Coseno
                xc = Ac*sin(2*pi*fc*tc+phi_c);
            elseif Fase =="-Seno"
                phi_c = pi; %-Seno
                xc = Ac*sin(2*pi*fc*tc+phi_c);
            else
                phi_c = 3*pi/2; %-Coseno
                xc = Ac*sin(2*pi*fc*tc+phi_c);
            end
            
            %AM Modulation
            xam = (1+m*xt).*xc;
            
            %FM Modulation
            xfm = Ac*sin(2*pi*fc*tc+nfm*xt);
            
            %PM Modulation
            Dxt = Ax*cos(2*pi*fx*tx);
            xpm = Ac*sin(2*pi*fc*tc+npm*Dxt);

            %Output--------------------------------------------------------
            plot(app.Grafica1, tc, xc), title(app.Grafica1, 'Datos Analogos - Carrier')
            plot(app.Grafica2, tx, xt), title(app.Grafica2, 'Datos Analogos - Voz')
            plot(app.Grafica3, tc, xam), title(app.Grafica3, 'Señal - AM')
            plot(app.Grafica4, tc, xfm), title(app.Grafica4, 'Señal - FM')
            plot(app.Grafica5, tc, xpm), title(app.Grafica5, 'Señal - PM')
        end

        % Value changed function: CuadriculaCheckBox
        function CuadriculaCheckBoxValueChanged(app, event)
            value = app.CuadriculaCheckBox.Value;
            if value == 1
                app.Grafica1.XGrid = "on";
                app.Grafica1.YGrid = "on";
                app.Grafica2.XGrid = "on";
                app.Grafica2.YGrid = "on";
                app.Grafica3.XGrid = "on";
                app.Grafica3.YGrid = "on";                
                app.Grafica4.XGrid = "on";
                app.Grafica4.YGrid = "on";
                app.Grafica5.XGrid = "on";
                app.Grafica5.YGrid = "on";
            else
                app.Grafica1.XGrid = "off";
                app.Grafica1.YGrid = "off";   
                app.Grafica2.XGrid = "off";
                app.Grafica2.YGrid = "off";  
                app.Grafica3.XGrid = "off";
                app.Grafica3.YGrid = "off";   
                app.Grafica4.XGrid = "off";
                app.Grafica4.YGrid = "off";  
                app.Grafica5.XGrid = "off";
                app.Grafica5.YGrid = "off";   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Modulacion_AnalogicaUIFigure = uifigure('Visible', 'off');
            app.Modulacion_AnalogicaUIFigure.Name = 'Modulacion_Analogica';
            app.Grafica3 = uiaxes(app.Modulacion_AnalogicaUIFigure);
            title(app.Grafica3, 'Title')
            xlabel(app.Grafica3, 'X')
            ylabel(app.Grafica3, 'Y')
            zlabel(app.Grafica3, 'Z')
            app.Grafica4 = uiaxes(app.Modulacion_AnalogicaUIFigure);
            title(app.Grafica4, 'Title')
            xlabel(app.Grafica4, 'X')
            ylabel(app.Grafica4, 'Y')
            zlabel(app.Grafica4, 'Z')
            app.Grafica5 = uiaxes(app.Modulacion_AnalogicaUIFigure);
            title(app.Grafica5, 'Title')
            xlabel(app.Grafica5, 'X')
            ylabel(app.Grafica5, 'Y')
            zlabel(app.Grafica5, 'Z')
            app.Grafica1 = uiaxes(app.Modulacion_AnalogicaUIFigure);
            title(app.Grafica1, 'Title')
            xlabel(app.Grafica1, 'X')
            ylabel(app.Grafica1, 'Y')
            zlabel(app.Grafica1, 'Z')
            app.Grafica2 = uiaxes(app.Modulacion_AnalogicaUIFigure);
            title(app.Grafica2, 'Title')
            xlabel(app.Grafica2, 'X')
            ylabel(app.Grafica2, 'Y')
            zlabel(app.Grafica2, 'Z')
            app.ModulacinAnalgicaLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.ModulacinAnalgicaLabel.Text = 'Modulación Analógica';
            app.AmplitudxEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.AmplitudxEditFieldLabel.Text = 'Amplitud x';
            app.AmplitudxEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.AmplitudxEditField.Value = 2;
            app.FrecuenciaxEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.FrecuenciaxEditFieldLabel.Text = 'Frecuencia x';
            app.FrecuenciaxEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.FrecuenciaxEditField.Value = 3400;
            app.DatosLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.DatosLabel.Text = 'Datos';
            app.CantidaddePeriodosxEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.CantidaddePeriodosxEditFieldLabel.Text = {'Cantidad de'; 'Periodos x'};
            app.CantidaddePeriodosxEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.CantidaddePeriodosxEditField.Value = 2;
            app.CarrierLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.CarrierLabel.Text = 'Carrier';
            app.AmplitudcEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.AmplitudcEditFieldLabel.Text = 'Amplitud c';
            app.AmplitudcEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.AmplitudcEditField.Value = 10;
            app.FrecuenciacEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.FrecuenciacEditFieldLabel.Text = 'Frecuencia c';
            app.FrecuenciacEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.FrecuenciacEditField.Value = 1000000;
            app.CantidaddePeriodoscEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.CantidaddePeriodoscEditFieldLabel.Text = {'Cantidad de'; 'Periodos c'};
            app.CantidaddePeriodoscEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.CantidaddePeriodoscEditField.Value = 13;
            app.IndicedemodulacinLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.IndicedemodulacinLabel.Text = {'Indice de '; 'modulación'};
            app.mEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.mEditField.Value = 0.6;
            app.nfmEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.nfmEditFieldLabel.Text = 'nfm';
            app.nfmEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.nfmEditField.Value = 2;
            app.npmEditFieldLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.npmEditFieldLabel.Text = 'npm';
            app.npmEditField = uieditfield(app.Modulacion_AnalogicaUIFigure, 'numeric');
            app.npmEditField.Value = 2;
            app.GenerarButton = uibutton(app.Modulacion_AnalogicaUIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.CuadriculaCheckBox = uicheckbox(app.Modulacion_AnalogicaUIFigure);
            app.CuadriculaCheckBox.ValueChangedFcn = createCallbackFcn(app, @CuadriculaCheckBoxValueChanged, true);
            app.CuadriculaCheckBox.Text = 'Cuadricula';
            app.FaseDropDownLabel = uilabel(app.Modulacion_AnalogicaUIFigure);
            app.FaseDropDownLabel.Text = 'Fase';
            app.FaseDropDown = uidropdown(app.Modulacion_AnalogicaUIFigure);
            app.FaseDropDown.Items = {'Seno', 'Coseno', '-Seno', '-Coseno'};
            app.FaseDropDown.Value = 'Seno';
            % Show the figure after all components are created
            app.Modulacion_AnalogicaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analog_Modulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Modulacion_AnalogicaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Modulacion_AnalogicaUIFigure)
        end
    end
end