classdef Fourier_Series < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Series_Furier               matlab.ui.Figure
        FaseDropDown                matlab.ui.control.DropDown
        FaseDropDownLabel           matlab.ui.control.Label
        Componentes_Frecuencia      matlab.ui.control.NumericEditField
        ComponentesdefrecuenciaEditField_2Label  matlab.ui.control.Label
        Cantidad_Periodos           matlab.ui.control.NumericEditField
        CantidaddeperiodosEditField_2Label  matlab.ui.control.Label
        Frecuencia                  matlab.ui.control.NumericEditField
        FrecuenciaEditField_2Label  matlab.ui.control.Label
        Amplitud                    matlab.ui.control.NumericEditField
        AmplitudEditField_3Label    matlab.ui.control.Label
        Tipo_Grafica                matlab.ui.control.DropDown
        TipoDropDownLabel           matlab.ui.control.Label
        GenerarButton               matlab.ui.control.Button
        Titulo                      matlab.ui.control.Label
        Grafica                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Generar(app)
            
        end

        % Callback function: not associated with a component
        function CuadriculaValueChanged(app, event)
            value = app.Cuadricula.Value;
            if value == "Si"
                app.Grafica.XGrid = "on";
                app.Grafica.YGrid = "on";
            else
                app.Grafica.XGrid = "off";
                app.Grafica.YGrid = "off";   
            end
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            % Input----------------------------------------------------
            A = app.Amplitud.Value;
            f = app.Frecuencia.Value;
            cp = app.Cantidad_Periodos.Value;
            num_cf = app.Componentes_Frecuencia.Value;
            Fase = app.FaseDropDown.Value;

            if app.Tipo_Grafica.Value == "Cuadrada"
                %Process---------------------------------------------------
                T = 1/f;
                t = linspace(0, cp*T, 1000);
                N_square = (4*A)/(pi); %Normalizacion

                %Square
                s_square = 0;
                for n=1:2:(2*(num_cf)-1)
                    s_square_f = (N_square/n)*sin(2*pi*n*f.*t);
                    s_square = s_square + s_square_f;
                end

                %Output---------------------------------------------------
                plot(app.Grafica, t, s_square), title(app.Grafica,'Señal Cuadrada')

            elseif app.Tipo_Grafica.Value == "Triangular"
                %Process---------------------------------------------------
                T = 1/f;
                t = linspace(0, cp*T, 1000);
                N_Triangle = (8*A)/(pi^2); %Normalizacion

                s_triangle = 0;
                %Triangle
                for n=1:2:(2*num_cf-1)
                    s_triangle_f = (N_Triangle/n^2)*cos(2*pi*n*f.*t);
                    s_triangle = s_triangle + s_triangle_f;
                end
                
                %Output---------------------------------------------------
                plot(app.Grafica, t, s_triangle), title(app.Grafica,'Señal Triangular')
                            
            elseif app.Tipo_Grafica.Value == "Diente Sierra"
                %Process---------------------------------------------------
                T = 1/f;
                t = linspace(0, cp*T, 1000);
                N_sawtooth = (2*A)/(pi); %Normalizacion

                s_sawtooth = 0;
                %Sawtooth
                for n=1:num_cf
                    s_sawtooth_f = ((-1)^(n+1))*(N_sawtooth/n)*sin(2*pi*n*f.*t);
                    s_sawtooth = s_sawtooth + s_sawtooth_f;
                end
                
                %Output---------------------------------------------------
                plot(app.Grafica, t, s_sawtooth), title(app.Grafica,'Señal Diente Sierra')

            elseif app.Tipo_Grafica.Value == "Sinusoidal"
                if Fase == "Seno"
                    %Process---------------------------------------------------
                    phi = 0; %Seno
                    T = 1/f;
                    t = linspace(0, cp*T, 1000);

                    %Sinusoidal
                    s_sinusoidal = A*sin(2*pi*f.*t + phi);

                    %Output
                    plot(app.Grafica, t, s_sinusoidal), title(app.Grafica,'Señal Seno')

                elseif Fase == "Coseno"
                    phi = pi/2; %Coseno
                    T = 1/f;
                    t = linspace(0, cp*T, 1000);

                    %Sinusoidal
                    s_sinusoidal = A*sin(2*pi*f.*t + phi);

                    %Output
                    plot(app.Grafica, t, s_sinusoidal), title(app.Grafica,'Señal Coseno')

                elseif Fase == "-Seno"
                    phi = pi; %-Seno
                    T = 1/f;
                    t = linspace(0, cp*T, 1000);

                    %Sinusoidal
                    s_sinusoidal = A*sin(2*pi*f.*t + phi);

                    %Output
                    plot(app.Grafica, t, s_sinusoidal), title(app.Grafica,'Señal -Seno')

                else
                    phi = 3*pi/2; %-Coseno
                    T = 1/f;
                    t = linspace(0, cp*T, 1000);

                    %Sinusoidal
                    s_sinusoidal = A*sin(2*pi*f.*t + phi);

                    %Output
                    plot(app.Grafica, t, s_sinusoidal), title(app.Grafica,'Señal -Coseno')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Series_Furier = uifigure('Visible', 'off');
            app.Series_Furier.Name = 'Series_Furier';
            app.Grafica = uiaxes(app.Series_Furier);
            title(app.Grafica, 'Title')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.Titulo = uilabel(app.Series_Furier);
            app.Titulo.Text = 'Interfaz Series de  Fourier';
            app.GenerarButton = uibutton(app.Series_Furier, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.TipoDropDownLabel = uilabel(app.Series_Furier);
            app.TipoDropDownLabel.Text = 'Tipo ';
            app.Tipo_Grafica = uidropdown(app.Series_Furier);
            app.Tipo_Grafica.Items = {'Cuadrada', 'Triangular', 'Diente Sierra', 'Sinusoidal'};
            app.Tipo_Grafica.Value = 'Cuadrada';
            app.AmplitudEditField_3Label = uilabel(app.Series_Furier);
            app.AmplitudEditField_3Label.Text = 'Amplitud';
            app.Amplitud = uieditfield(app.Series_Furier, 'numeric');
            app.Amplitud.Value = 10;
            app.FrecuenciaEditField_2Label = uilabel(app.Series_Furier);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.Frecuencia = uieditfield(app.Series_Furier, 'numeric');
            app.Frecuencia.Value = 3400;
            app.CantidaddeperiodosEditField_2Label = uilabel(app.Series_Furier);
            app.CantidaddeperiodosEditField_2Label.Text = {'Cantidad de'; 'periodos'};
            app.Cantidad_Periodos = uieditfield(app.Series_Furier, 'numeric');
            app.Cantidad_Periodos.Value = 3;
            app.ComponentesdefrecuenciaEditField_2Label = uilabel(app.Series_Furier);
            app.ComponentesdefrecuenciaEditField_2Label.Text = {'Componentes de'; 'frecuencia'};
            app.Componentes_Frecuencia = uieditfield(app.Series_Furier, 'numeric');
            app.Componentes_Frecuencia.Value = 5;
            app.FaseDropDownLabel = uilabel(app.Series_Furier);
            app.FaseDropDownLabel.Text = 'Fase';
            app.FaseDropDown = uidropdown(app.Series_Furier);
            app.FaseDropDown.Items = {'Seno', 'Coseno', '-Seno', '-Coseno'};
            app.FaseDropDown.Value = 'Seno';
            % Show the figure after all components are created
            app.Series_Furier.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fourier_Series

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Series_Furier)

            % Execute the startup function
            runStartupFcn(app, @Generar)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Series_Furier)
        end
    end
end