classdef Fourier_Transform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Transformada_FourierUIFigure  matlab.ui.Figure
        CuadriculaCheckBox            matlab.ui.control.CheckBox
        GenerarButton                 matlab.ui.control.Button
        Amplitud                      matlab.ui.control.NumericEditField
        AmplitudEditField_3Label      matlab.ui.control.Label
        Frecuencia                    matlab.ui.control.NumericEditField
        FrecuenciaEditField_2Label    matlab.ui.control.Label
        Componentes_Frecuencia        matlab.ui.control.NumericEditField
        ComponentesdefrecuenciaEditField_2Label  matlab.ui.control.Label
        Cantidad_Periodos             matlab.ui.control.NumericEditField
        CantidaddeperiodosEditField_2Label  matlab.ui.control.Label
        TransformadadeFourierLabel    matlab.ui.control.Label
        Grafica2                      matlab.ui.control.UIAxes
        Grafica1                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            %Input---------------------------------------------------------
            A = app.Amplitud.Value;
            f = app.Frecuencia.Value;
            cp = app.Cantidad_Periodos.Value;
            num_cf = app.Componentes_Frecuencia.Value;

            % Process------------------------------------------------------
            T = 1/f;
            t = linspace(0, cp*T, 1000);
            N_square = (4*A)/(pi); %Normalizacion

            s_square = 0;
            for n=1:2:(2*num_cf-1)
                s_square = s_square + (N_square/n)*sin(2*pi*n*f*t);
                s_square_tf = abs(fft(s_square));
            end

            %Output--------------------------------------------------------
            plot(app.Grafica1, s_square), title(app.Grafica1,'Señal en el dominio del Tiempo')
            plot(app.Grafica2, s_square_tf(1:100)), title(app.Grafica2,'Señal en el dominio de la Frecuencia')
        end

        % Value changed function: CuadriculaCheckBox
        function CuadriculaCheckBoxValueChanged(app, event)
            value = app.CuadriculaCheckBox.Value;
             if value == 1
                app.Grafica1.XGrid = "on";
                app.Grafica1.YGrid = "on";
                app.Grafica2.XGrid = "on";
                app.Grafica2.YGrid = "on";
            else
                app.Grafica1.XGrid = "off";
                app.Grafica1.YGrid = "off";   
                app.Grafica2.XGrid = "off";
                app.Grafica2.YGrid = "off";  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Transformada_FourierUIFigure = uifigure('Visible', 'off');
            app.Transformada_FourierUIFigure.Name = 'Transformada_Fourier';
            app.Grafica1 = uiaxes(app.Transformada_FourierUIFigure);
            title(app.Grafica1, 'Title')
            xlabel(app.Grafica1, 'X')
            ylabel(app.Grafica1, 'Y')
            zlabel(app.Grafica1, 'Z')
            app.Grafica2 = uiaxes(app.Transformada_FourierUIFigure);
            title(app.Grafica2, 'Title')
            xlabel(app.Grafica2, 'X')
            ylabel(app.Grafica2, 'Y')
            zlabel(app.Grafica2, 'Z')
            app.TransformadadeFourierLabel = uilabel(app.Transformada_FourierUIFigure);
            app.TransformadadeFourierLabel.Text = 'Transformada de Fourier';
            app.CantidaddeperiodosEditField_2Label = uilabel(app.Transformada_FourierUIFigure);
            app.CantidaddeperiodosEditField_2Label.Text = {'Cantidad de'; 'periodos'};
            app.Cantidad_Periodos = uieditfield(app.Transformada_FourierUIFigure, 'numeric');
            app.Cantidad_Periodos.Value = 4;
            app.ComponentesdefrecuenciaEditField_2Label = uilabel(app.Transformada_FourierUIFigure);
            app.ComponentesdefrecuenciaEditField_2Label.Text = {'Componentes de'; 'frecuencia'};
            app.Componentes_Frecuencia = uieditfield(app.Transformada_FourierUIFigure, 'numeric');
            app.Componentes_Frecuencia.Value = 3;
            app.FrecuenciaEditField_2Label = uilabel(app.Transformada_FourierUIFigure);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.Frecuencia = uieditfield(app.Transformada_FourierUIFigure, 'numeric');
            app.Frecuencia.Value = 3400;
            app.AmplitudEditField_3Label = uilabel(app.Transformada_FourierUIFigure);
            app.AmplitudEditField_3Label.Text = 'Amplitud';
            app.Amplitud = uieditfield(app.Transformada_FourierUIFigure, 'numeric');
            app.Amplitud.Value = 5;
            app.GenerarButton = uibutton(app.Transformada_FourierUIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.CuadriculaCheckBox = uicheckbox(app.Transformada_FourierUIFigure);
            app.CuadriculaCheckBox.ValueChangedFcn = createCallbackFcn(app, @CuadriculaCheckBoxValueChanged, true);
            app.CuadriculaCheckBox.Text = 'Cuadricula';
            % Show the figure after all components are created
            app.Transformada_FourierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fourier_Transform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Transformada_FourierUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Transformada_FourierUIFigure)
        end
    end
end