classdef Sinusoidal_Signals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Senales_Sinusoidales         matlab.ui.Figure
        InterfazSealesSinusoidalesLabel  matlab.ui.control.Label
        CuadriculaCheckBox           matlab.ui.control.CheckBox
        GenerarGraficaButton         matlab.ui.control.Button
        FaseDropDown                 matlab.ui.control.DropDown
        FaseDropDownLabel            matlab.ui.control.Label
        CantidaddeperiodosEditField  matlab.ui.control.NumericEditField
        CantidaddeperiodosEditFieldLabel  matlab.ui.control.Label
        FrecuenciaEditField          matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel     matlab.ui.control.Label
        AmplitudEditField            matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel       matlab.ui.control.Label
        Grafica                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarGraficaButton
        function GenerarGraficaButtonPushed(app, event)
            %Input---------------------------------------------------------
            A = app.AmplitudEditField.Value;
            f = app.FrecuenciaEditField.Value;
            cp = app.CantidaddeperiodosEditField.Value;
            Fase = app.FaseDropDown.Value;
            if Fase == "Seno"
                %Process---------------------------------------------------
                phi = 0; %Seno
                T = 1/f;
                t = linspace(0, cp*T, 1000);

                %Sinusoidal
                s_sinusoidal = A*sin(2*pi*f.*t + phi);

                %Output
                plot(app.Grafica, t, s_sinusoidal), title(app.Grafica,'Señal Seno')

            elseif Fase == "Coseno"
                phi = pi/2; %Coseno
                T = 1/f;
                t = linspace(0, cp*T, 1000);

                %Sinusoidal
                s_sinusoidal = A*sin(2*pi*f.*t + phi);

                %Output
                plot(app.Grafica, t, s_sinusoidal), title(app.Grafica,'Señal Coseno')

            elseif Fase == "-Seno"
                phi = pi; %-Seno
                T = 1/f;
                t = linspace(0, cp*T, 1000);

                %Sinusoidal
                s_sinusoidal = A*sin(2*pi*f.*t + phi);

                %Output
                plot(app.Grafica, t, s_sinusoidal), title(app.Grafica,'Señal -Seno')

            else
                phi = 3*pi/2; %-Coseno
                T = 1/f;
                t = linspace(0, cp*T, 1000);

                %Sinusoidal
                s_sinusoidal = A*sin(2*pi*f.*t + phi);

                %Output
                plot(app.Grafica, t, s_sinusoidal), title(app.Grafica,'Señal -Coseno')
            end
        end

        % Value changed function: CuadriculaCheckBox
        function CuadriculaCheckBoxValueChanged(app, event)
            value = app.CuadriculaCheckBox.Value;
            if value == 1
                app.Grafica.XGrid = "on";
                app.Grafica.YGrid = "on";
            else
                app.Grafica.XGrid = "off";
                app.Grafica.YGrid = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Senales_Sinusoidales = uifigure('Visible', 'off');
            app.Senales_Sinusoidales.Name = 'Señales Sinusoidales';
            app.Grafica = uiaxes(app.Senales_Sinusoidales);
            title(app.Grafica, 'Title')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.AmplitudEditFieldLabel = uilabel(app.Senales_Sinusoidales);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.Senales_Sinusoidales, 'numeric');
            app.AmplitudEditField.Value = 10;
            app.FrecuenciaEditFieldLabel = uilabel(app.Senales_Sinusoidales);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.Senales_Sinusoidales, 'numeric');
            app.FrecuenciaEditField.Value = 3400;
            app.CantidaddeperiodosEditFieldLabel = uilabel(app.Senales_Sinusoidales);
            app.CantidaddeperiodosEditFieldLabel.Text = {'Cantidad de'; 'periodos'};
            app.CantidaddeperiodosEditField = uieditfield(app.Senales_Sinusoidales, 'numeric');
            app.CantidaddeperiodosEditField.Value = 2;
            app.FaseDropDownLabel = uilabel(app.Senales_Sinusoidales);
            app.FaseDropDownLabel.Text = 'Fase';
            app.FaseDropDown = uidropdown(app.Senales_Sinusoidales);
            app.FaseDropDown.Items = {'Seno', 'Coseno', '-Seno', '-Coseno'};
            app.FaseDropDown.Value = 'Seno';
            app.GenerarGraficaButton = uibutton(app.Senales_Sinusoidales, 'push');
            app.GenerarGraficaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarGraficaButtonPushed, true);
            app.GenerarGraficaButton.Text = 'Generar Grafica';
            app.CuadriculaCheckBox = uicheckbox(app.Senales_Sinusoidales);
            app.CuadriculaCheckBox.ValueChangedFcn = createCallbackFcn(app, @CuadriculaCheckBoxValueChanged, true);
            app.CuadriculaCheckBox.Text = 'Cuadricula';
            app.InterfazSealesSinusoidalesLabel = uilabel(app.Senales_Sinusoidales);
            app.InterfazSealesSinusoidalesLabel.Text = 'Interfaz Señales Sinusoidales';
            % Show the figure after all components are created
            app.Senales_Sinusoidales.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sinusoidal_Signals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Senales_Sinusoidales)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Senales_Sinusoidales)
        end
    end
end